classdef SegmentCellsExtractCalciumSignalingStatsV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadOMEImageDataButton          matlab.ui.control.Button
        ApplybatchonseveraldatasetsusingsamesettingsButton  matlab.ui.control.Button
        seriesSliderLabel               matlab.ui.control.Label
        seriesSlider                    matlab.ui.control.Slider
        RawdatatracepeaksdetectionPanel  matlab.ui.container.Panel
        MinPeakProminenceEditFieldLabel  matlab.ui.control.Label
        MinPeakProminenceEditField      matlab.ui.control.NumericEditField
        MinPeakDistanceEditFieldLabel   matlab.ui.control.Label
        MinPeakDistanceEditField        matlab.ui.control.NumericEditField
        MinPeakWidthEditFieldLabel      matlab.ui.control.Label
        MinPeakWidthEditField           matlab.ui.control.NumericEditField
        DefineTheCellCenterPositionsPanel  matlab.ui.container.Panel
        CellCentersDetectionSliderLabel  matlab.ui.control.Label
        CellCentersDetectionSlider      matlab.ui.control.Slider
        CellradiusfilterEditFieldLabel  matlab.ui.control.Label
        CellradiusfilterEditField       matlab.ui.control.NumericEditField
        SelectimagetouseforfindingcellcentersDropDownLabel  matlab.ui.control.Label
        SelectimagetouseforfindingcellcentersDropDown  matlab.ui.control.DropDown
        DefineBinarizationThresholdSliderLabel  matlab.ui.control.Label
        DefineBinarizationThresholdSlider  matlab.ui.control.Slider
        CalculateBinaryForCenterDetectionButton  matlab.ui.control.Button
        DetectCellCentersButton         matlab.ui.control.Button
        ApplyWatershedAndDefineCellAreasPanel  matlab.ui.container.Panel
        ApplyWatershedButton            matlab.ui.control.Button
        FinalSegmentationButton         matlab.ui.control.Button
        DisplayResultsDropDownLabel     matlab.ui.control.Label
        DisplayResultsDropDown          matlab.ui.control.DropDown
        AreaUpperBoundEditFieldLabel    matlab.ui.control.Label
        AreaUpperBoundEditField         matlab.ui.control.NumericEditField
        AreaLowerBoundEditFieldLabel    matlab.ui.control.Label
        AreaLowerBoundEditField         matlab.ui.control.NumericEditField
        DefineOverallCellMaskPanel      matlab.ui.container.Panel
        BinariseImage2SliderLabel       matlab.ui.control.Label
        BinariseImage2Slider            matlab.ui.control.Slider
        ImageforoverallcellshapebinaryDropDownLabel  matlab.ui.control.Label
        ImageforoverallcellshapebinaryDropDown  matlab.ui.control.DropDown
        CalculateOverallCellMaskButton  matlab.ui.control.Button
        DetrendeddatatracepeaksdetectionPanel  matlab.ui.container.Panel
        MinPeakProminenceEditField_2Label  matlab.ui.control.Label
        MinPeakProminenceEditField_2    matlab.ui.control.NumericEditField
        MinPeakDistanceEditField_2Label  matlab.ui.control.Label
        MinPeakDistanceEditField_2      matlab.ui.control.NumericEditField
        MinPeakWidthEditField_2Label    matlab.ui.control.Label
        MinPeakWidthEditField_2         matlab.ui.control.NumericEditField
        ApplyanalysistoallcellsusingdefinedsettingsButton  matlab.ui.control.Button
        DisplayingIntensityTracePerCellPanel  matlab.ui.container.Panel
        DisplayIntensityCellEditFieldLabel  matlab.ui.control.Label
        DisplayIntensityCellEditField   matlab.ui.control.NumericEditField
        MovingAverageRangeframesOverallShapeDetrendingEditFieldLabel  matlab.ui.control.Label
        MovingAverageRangeframesOverallShapeDetrendingEditField  matlab.ui.control.NumericEditField
        DisplaycellintensitytracesandpeaksfoundButton  matlab.ui.control.Button
        Displaythiscellzoominaxes1Button  matlab.ui.control.StateButton
        MovingAverageRangeframesAveIntensitySmoothingEditFieldLabel  matlab.ui.control.Label
        MovingAverageRangeframesAveIntensitySmoothingEditField  matlab.ui.control.NumericEditField
        SmoothButton                    matlab.ui.control.StateButton
        NormalizeAverageIntensityTracesButton  matlab.ui.control.StateButton
        LoadAnalysisParametersFileButton  matlab.ui.control.Button
        SaveAnalysisParametersFileButton  matlab.ui.control.Button
        SaveSegmentationResultsButton   matlab.ui.control.Button
        UsePredefinedWatershedOutputsButton  matlab.ui.control.StateButton
        ManuallySelectROIsForQuantificationPanel  matlab.ui.container.Panel
        NumberofROIsToSelectEditFieldLabel  matlab.ui.control.Label
        NumberofROIsToSelectEditField   matlab.ui.control.NumericEditField
        ROItypeDropDownLabel            matlab.ui.control.Label
        ROItypeDropDown                 matlab.ui.control.DropDown
        SelectROIsInsideImagetoDefineCellsButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fname
        im % Description
        maxZdatach1
        im1
        im2
        norm1
        norm2
        bw1
        cellsize
        thresh1a
        thresh1b
        thresh2
        out
        bw2
        invconv
        aveim
        Lb
        I_eq_c
        I_mod
        areaLB
        areaUB
        MovAvRan=10;
        choice1
        choice2
        DispCell
        MinPeakProm1
        MinPeakDist1
        MinPeakWidth1
        MinPeakProm2
        MinPeakDist2
        MinPeakWidth2
        cellser
        DisplayCell
        slider
        NormalizeTrace
        SmoothAve
        SmoothWindow
        UsePreDefined
        NumberOfROI
        ROIChoice
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOMEImageDataButton
        function LoadOMEImageDataButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.czi;*.nd2;*.oir;*.oif;*.lif','OME files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                app.fname=fname;
                [im,specs]=readOMEAlternativeGUI(app.UIAxes3,app.fname);
                app.im=double(im);
                %updateimage(app, fname);
                meta=GetOMEData(app.fname);
                imagesc(app.UIAxes,squeeze(app.im(:,:,1,1,1)));
                app.cellsize=10;
                app.areaLB=50;
                app.areaUB=600;
                app.MovAvRan=10;
                app.DispCell=1;
                app.MinPeakProm1=3;
                app.MinPeakDist1=2;
                app.MinPeakWidth1=2;
                app.MinPeakProm2=2;
                app.MinPeakDist2=3;
                app.MinPeakWidth2=3;
                app.DisplayCell=0;
                app.NormalizeTrace=0;
                app.SmoothAve=0;
                app.SmoothWindow=3;
                app.UsePreDefined=0;
                app.NumberOfROI=3;
                app.ROIChoice='Circle';
                app.seriesSlider.Limits = [1 size(app.im,4)];
                app.maxZdatach1=squeeze(max(app.im(:,:,:,:,1),[],3));
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.im,2)-20,size(app.im,1)-20,num2str(1),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.im,2)])
                ylim(app.UIAxes,[1 size(app.im,1)])
            end
        end

        % Button pushed function: LoadAnalysisParametersFileButton
        function LoadAnalysisParametersFileButtonPushed(app, event)
            filterspec = {'*.mat','Select Parameter file to load'};
            [f, p] = uigetfile(filterspec);
            load([p f]);%,'thresh1a','thresh1b','thresh2','areaUB','areaLB','MovAvRan','cellsize','choice1','choice2','MinPeakProm1','MinPeakProm2','MinPeakDist1','MinPeakDist2','MinPeakWidth1','MinPeakWidth2','NormalizeTrace','SmoothAve','SmoothWindow')
            app.DefineBinarizationThresholdSlider.Value=thresh1a*10;
            app.thresh1a=thresh1a;
            app.CellCentersDetectionSlider.Value=thresh1b*10;
            app.thresh1b=thresh1b;
            app.BinariseImage2Slider.Value=thresh2*10;
            app.thresh2=thresh2;
            app.AreaUpperBoundEditField.Value=areaUB;
            app.areaUB=areaUB;
            app.AreaLowerBoundEditField.Value=areaLB;
            app.areaLB=areaLB;
            app.MovingAverageRangeframesOverallShapeDetrendingEditField.Value=MovAvRan;
            app.MovAvRan=MovAvRan;
            app.CellradiusfilterEditField.Value=cellsize;
            app.cellsize=cellsize;
            app.SelectimagetouseforfindingcellcentersDropDown.Value=choice1;
            app.choice1=choice1;
            app.ImageforoverallcellshapebinaryDropDown.Value=choice2;
            app.choice2=choice2;
            app.MinPeakProminenceEditField.Value=MinPeakProm1;
            app.MinPeakProm1=MinPeakProm1;
            app.MinPeakProminenceEditField_2.Value=MinPeakProm2;
            app.MinPeakProm2=MinPeakProm2;
            app.MinPeakDistanceEditField.Value=MinPeakDist1;
            app.MinPeakDist1=MinPeakDist1;
            app.MinPeakDistanceEditField_2.Value=MinPeakDist2;
            app.MinPeakDist2=MinPeakDist2;
            app.MinPeakWidthEditField.Value=MinPeakWidth1;
            app.MinPeakWidth1=MinPeakWidth1;
            app.MinPeakWidthEditField_2.Value=MinPeakWidth2;
            app.MinPeakWidth2=MinPeakWidth2;
            app.NormalizeAverageIntensityTracesButton.Value=NormalizeTrace;
            app.NormalizeTrace=NormalizeTrace;
            app.SmoothButton.Value=SmoothAve;
            app.SmoothAve=SmoothAve;
            app.MovingAverageRangeframesAveIntensitySmoothingEditField.Value=SmoothWindow;
            app.SmoothWindow=SmoothWindow;
            
        end

        % Value changing function: seriesSlider
        function seriesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.slider=ceil(changingValue);%ceil(size(app.im,4)*changingValue/100);
            
            if app.DisplayCell==0
                cla(app.UIAxes)
                imagesc(app.UIAxes,app.maxZdatach1(:,:,app.slider));
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.im,2)-30,size(app.im,1)-20,num2str(app.slider),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.im,2)])
                ylim(app.UIAxes,[1 size(app.im,1)])
            elseif app.DisplayCell==1
                cla(app.UIAxes)
                imagesc(app.UIAxes,app.cellser(:,:,app.slider));
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.cellser,2)-2,size(app.cellser,1)-2,num2str(app.slider),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.cellser,2)])
                ylim(app.UIAxes,[1 size(app.cellser,1)])
            end
            
        end

        % Value changed function: 
        % SelectimagetouseforfindingcellcentersDropDown
        function SelectimagetouseforfindingcellcentersDropDownValueChanged(app, event)
            value = app.SelectimagetouseforfindingcellcentersDropDown.Value;
            app.choice1=value;
            if strcmp(value,'z=1; Ch=2')
                app.im1=squeeze(mean(app.im(:,:,1,:,2),4));
            elseif strcmp(value,'z=2; Ch=2')
                app.im1=squeeze(mean(app.im(:,:,2,:,2),4));
            elseif strcmp(value,'z=3; Ch=2')
                app.im1=squeeze(mean(app.im(:,:,3,:,2),4));
            elseif strcmp(value,'max Z; Ch=2')
                app.im1=squeeze(mean(max(app.im(:,:,:,:,2),[],3),4));
            elseif strcmp(value,'<Z>; Ch=2')
                app.im1=squeeze(mean(mean(app.im(:,:,:,:,2),3),4));
            end
            cla(app.UIAxes)
            imagesc(app.UIAxes,(app.im1));
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.im1,2)-20,size(app.im1,1)-20,num2str(app.slider),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: CellradiusfilterEditField
        function CellradiusfilterEditFieldValueChanged(app, event)
            value = app.CellradiusfilterEditField.Value;
            app.cellsize=value;
        end

        % Value changing function: DefineBinarizationThresholdSlider
        function DefineBinarizationThresholdSliderValueChanging(app, event)
            cla(app.UIAxes)
            changingValue = event.Value;
            app.norm1=app.im1/max(app.im1(:));
            app.bw1=app.norm1>changingValue/10;
            imagesc(app.UIAxes,app.bw1);
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: DefineBinarizationThresholdSlider
        function DefineBinarizationThresholdSliderValueChanged(app, event)
            value = app.DefineBinarizationThresholdSlider.Value;
            app.thresh1a=value/10;
        end

        % Button pushed function: 
        % CalculateBinaryForCenterDetectionButton
        function CalculateBinaryForCenterDetectionButtonPushed(app, event)
            if strcmp(app.choice1,'z=1; Ch=2')
                app.im1=squeeze(mean(app.im(:,:,1,:,2),4));
            elseif strcmp(app.choice1,'z=2; Ch=2')
                app.im1=squeeze(mean(app.im(:,:,2,:,2),4));
            elseif strcmp(app.choice1,'z=3; Ch=2')
                app.im1=squeeze(mean(app.im(:,:,3,:,2),4));
            elseif strcmp(app.choice1,'max Z; Ch=2')
                app.im1=squeeze(mean(max(app.im(:,:,:,:,2),[],3),4));
            elseif strcmp(app.choice1,'<Z>; Ch=2')
                app.im1=squeeze(mean(mean(app.im(:,:,:,:,2),3),4));
            end
            app.norm1=app.im1/max(app.im1(:));
            app.bw1=app.norm1>app.thresh1a;
            imagesc(app.UIAxes,app.bw1);
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changing function: CellCentersDetectionSlider
        function CellCentersDetectionSliderValueChanging(app, event)
            cla(app.UIAxes)
            changingValue = event.Value/10;
            % 1) find cell centers using average image
            app.norm1=app.im1/max(app.im1(:));
            bw=app.norm1>app.thresh1a;
            D = bwdist(~bw);
            app.out=pkfnd(D/max(D(:)),changingValue,app.cellsize);
            %[app.invconv,h2]=smoothSeries(app.im1,app.cellsize,'g','p');
            imagesc(app.UIAxes,D);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.out(:,1),app.out(:,2),'ro')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: CellCentersDetectionSlider
        function CellCentersDetectionSliderValueChanged(app, event)
            value = app.CellCentersDetectionSlider.Value;
            app.thresh1b=value/10;
        end

        % Button pushed function: DetectCellCentersButton
        function DetectCellCentersButtonPushed(app, event)
            app.norm1=app.im1/max(app.im1(:));
            bw=app.norm1>app.thresh1a;
            D = bwdist(~bw);
            app.out=pkfnd(D/max(D(:)),app.thresh1b,app.cellsize);
            imagesc(app.UIAxes,D);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.out(:,1),app.out(:,2),'ro')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
        end

        % Value changed function: 
        % ImageforoverallcellshapebinaryDropDown
        function ImageforoverallcellshapebinaryDropDownValueChanged(app, event)
            value = app.ImageforoverallcellshapebinaryDropDown.Value;
            app.choice2=value;
            if strcmp(value,'z=1; Ch=2')
                app.im2=squeeze(mean(app.im(:,:,1,:,2),4));
            elseif strcmp(value,'z=2; Ch=2')
                app.im2=squeeze(mean(app.im(:,:,2,:,2),4));
            elseif strcmp(value,'z=3; Ch=2')
                app.im2=squeeze(mean(app.im(:,:,3,:,2),4));
            elseif strcmp(value,'max Z; Ch=2')
                app.im2=squeeze(mean(max(app.im(:,:,:,:,2),[],3),4));
            elseif strcmp(value,'<Z>; Ch=2')
                app.im2=squeeze(mean(mean(app.im(:,:,:,:,2),3),4));
            end
            cla(app.UIAxes)
            imagesc(app.UIAxes,(app.im2));
            xlim(app.UIAxes,[1 size(app.im2,2)])
            ylim(app.UIAxes,[1 size(app.im2,1)])
        end

        % Value changing function: BinariseImage2Slider
        function BinariseImage2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.norm2=app.im2/max(app.im2(:));
            bw2=app.norm2>changingValue/10;
            % filter binary
            cc = bwconncomp(bw2);
            stats = regionprops(cc, 'Area');
            idx = find([stats.Area] >pi*(app.cellsize^2));
            app.bw2 = ismember(labelmatrix(cc), idx);
            cla(app.UIAxes);
            imagesc(app.UIAxes,app.bw2);
        end

        % Value changed function: BinariseImage2Slider
        function BinariseImage2SliderValueChanged(app, event)
            value = app.BinariseImage2Slider.Value;
            app.thresh2=value/10;
        end

        % Button pushed function: CalculateOverallCellMaskButton
        function CalculateOverallCellMaskButtonPushed(app, event)
            if strcmp(app.choice2,'z=1; Ch=2')
                app.im2=squeeze(mean(app.im(:,:,1,:,2),4));
            elseif strcmp(app.choice2,'z=2; Ch=2')
                app.im2=squeeze(mean(app.im(:,:,2,:,2),4));
            elseif strcmp(app.choice2,'z=3; Ch=2')
                app.im2=squeeze(mean(app.im(:,:,3,:,2),4));
            elseif strcmp(app.choice2,'max Z; Ch=2')
                app.im2=squeeze(mean(max(app.im(:,:,:,:,2),[],3),4));
            elseif strcmp(app.choice2,'<Z>; Ch=2')
                app.im2=squeeze(mean(mean(app.im(:,:,:,:,2),3),4));
            end
            app.norm2=app.im2/max(app.im2(:));
            bw2=app.norm2>app.thresh2;
            % filter binary
            cc = bwconncomp(bw2);
            stats = regionprops(cc, 'Area');
            idx = find([stats.Area] >pi*(app.cellsize^2));
            app.bw2 = ismember(labelmatrix(cc), idx);
            cla(app.UIAxes)
            imagesc(app.UIAxes,(app.bw2));
            xlim(app.UIAxes,[1 size(app.im2,2)])
            ylim(app.UIAxes,[1 size(app.im2,1)])
        end

        % Button pushed function: ApplyWatershedButton
        function ApplyWatershedButtonPushed(app, event)
            % 3) combine info apply watershed
            [app.aveim,h2]=smoothSeries(app.norm2,1.5,'g','p');
            mask_em=accumarray(round([app.out(:,2),app.out(:,1)]),ones(1,size(app.out,1)), [size(app.bw2,1),size(app.bw2,2)]);
            app.I_eq_c = imcomplement((app.aveim/max(app.aveim(:))).^2);
            app.I_mod = imimposemin(app.I_eq_c, ~app.bw2 | mask_em);
            app.Lb = watershed(app.I_mod,8);
            rgb=label2rgb(app.Lb,'jet','k','shuffle');
            imagesc(app.UIAxes,rgb)
        end

        % Drop down opening function: DisplayResultsDropDown
        function DisplayResultsDropDownOpening(app, event)
            imagesc(app.UIAxes,app.I_eq_c)
        end

        % Value changed function: DisplayResultsDropDown
        function DisplayResultsDropDownValueChanged(app, event)
            value = app.DisplayResultsDropDown.Value;
            if strcmp(value,'Inverse Average')
                imagesc(app.UIAxes,app.I_eq_c)
            elseif strcmp(value,'Inverse Average Constrained')
                imagesc(app.UIAxes,app.I_mod)
            elseif strcmp(value,'Watershed Results')
                rgb=label2rgb(app.Lb,'jet','k','shuffle');
                imagesc(app.UIAxes,rgb)
            end
            
        end

        % Value changed function: AreaLowerBoundEditField
        function AreaLowerBoundEditFieldValueChanged(app, event)
            value = app.AreaLowerBoundEditField.Value;
            app.areaLB=value;
        end

        % Value changed function: AreaUpperBoundEditField
        function AreaUpperBoundEditFieldValueChanged(app, event)
            value = app.AreaUpperBoundEditField.Value;
            app.areaUB=value;
        end

        % Button pushed function: FinalSegmentationButton
        function FinalSegmentationButtonPushed(app, event)
            Lb=app.Lb;
            firststat = regionprops(Lb);
            areas=[firststat.Area];
            ind1=find(areas<app.areaUB);
            ind2=find(areas>app.areaLB);
            indGood=intersect(ind1,ind2);
            Lb2=zeros(size(Lb));
            for i=1:length(indGood)
                ind=find(Lb==indGood(i));
                Lb2(ind)=i;
            end
            app.Lb=Lb2;
            rgb=label2rgb(app.Lb,'jet','k','shuffle');
            stats=regionprops(app.Lb,'Centroid');
            xy=[stats.Centroid];
            
            cla(app.UIAxes)
            imagesc(app.UIAxes,rgb)
            hold(app.UIAxes,"on");
            for cel=1:size(stats,1)
                text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                hold(app.UIAxes,"on");
            end
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
            exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refImage.png'])
        end

        % Value changed function: NumberofROIsToSelectEditField
        function NumberofROIsToSelectEditFieldValueChanged(app, event)
            value = app.NumberofROIsToSelectEditField.Value;
            app.NumberOfROI=value;
        end

        % Value changed function: ROItypeDropDown
        function ROItypeDropDownValueChanged(app, event)
            value = app.ROItypeDropDown.Value;
            app.ROIChoice=value;
        end

        % Button pushed function: 
        % SelectROIsInsideImagetoDefineCellsButton
        function SelectROIsInsideImagetoDefineCellsButtonPushed(app, event)
            cla(app.UIAxes)
            imagesc(app.UIAxes,(app.norm2));
            xlim(app.UIAxes,[1 size(app.im2,2)])
            ylim(app.UIAxes,[1 size(app.im2,1)])
            app.Lb=zeros(size(app.norm2));
            if strcmp(app.ROIChoice,'Circle')
                for i=1:app.NumberOfROI
                    roi=images.roi.Circle(app.UIAxes,'Color','c','StripeColor','r');
                    draw(roi)
                    mask=createMask(roi);
                    app.Lb=app.Lb+i*mask;
                end
            elseif strcmp(app.ROIChoice,'Ellipse')
                for i=1:app.NumberOfROI
                    roi=images.roi.Ellipse(app.UIAxes,'Color','c','StripeColor','r');
                    draw(roi)
                    mask=createMask(roi);
                    app.Lb=app.Lb+i*mask;
                end
            elseif strcmp(app.ROIChoice,'Rectangle')
                for i=1:app.NumberOfROI
                    roi=images.roi.Rectangle(app.UIAxes,'Color','c','StripeColor','r');
                    draw(roi)
                    mask=createMask(roi);
                    app.Lb=app.Lb+i*mask;
                end
            elseif strcmp(app.ROIChoice,'Polygon')
                for i=1:app.NumberOfROI
                    roi=images.roi.Polygon(app.UIAxes,'Color','c','StripeColor','r');
                    draw(roi)
                    mask=createMask(roi);
                    app.Lb=app.Lb+i*mask;
                end
            end
        end

        % Button pushed function: SaveSegmentationResultsButton
        function SaveSegmentationResultsButtonPushed(app, event)
            Lb=app.Lb;
            thresh1a=app.thresh1a;
            thresh1b=app.thresh1b;
            thresh2=app.thresh2;
            areaUB=app.areaUB;
            areaLB=app.areaLB;
            cellsize=app.cellsize;
            choice1=app.choice1;
            choice2=app.choice2;
            save([app.fname(1:end-4) '_Segmentation.mat'],'Lb','thresh1a','thresh1b','thresh2','areaUB','areaLB','cellsize','choice1','choice2')
            
        end

        % Value changed function: DisplayIntensityCellEditField
        function DisplayIntensityCellEditFieldValueChanged(app, event)
            value = app.DisplayIntensityCellEditField.Value;
            app.DispCell=value;
        end

        % Value changed function: 
        % MovingAverageRangeframesOverallShapeDetrendingEditField
        function MovingAverageRangeframesOverallShapeDetrendingEditFieldValueChanged(app, event)
            value = app.MovingAverageRangeframesOverallShapeDetrendingEditField.Value;
            app.MovAvRan=value;
        end

        % Value changed function: 
        % NormalizeAverageIntensityTracesButton
        function NormalizeAverageIntensityTracesButtonValueChanged(app, event)
            value = app.NormalizeAverageIntensityTracesButton.Value;
            app.NormalizeTrace=value;
        end

        % Value changed function: SmoothButton
        function SmoothButtonValueChanged(app, event)
            value = app.SmoothButton.Value;
            app.SmoothAve=value;
        end

        % Value changed function: 
        % MovingAverageRangeframesAveIntensitySmoothingEditField
        function MovingAverageRangeframesAveIntensitySmoothingEditFieldValueChanged(app, event)
            value = app.MovingAverageRangeframesAveIntensitySmoothingEditField.Value;
            app.SmoothWindow=value;
        end

        % Button pushed function: 
        % DisplaycellintensitytracesandpeaksfoundButton
        function DisplaycellintensitytracesandpeaksfoundButtonPushed(app, event)
            cell=app.DispCell;
            maskcell=app.Lb==cell;
            [row,col]=find(maskcell);
            maskcell2=squeeze(maskcell(min(row):max(row),min(col):max(col)));
            cellser=squeeze(app.maxZdatach1(min(row):max(row),min(col):max(col),:));
            app.cellser=cellser;
            bigmask=repmat(maskcell2,[1 1 size(cellser,3)]);
            aveincell=mean(cellser(find(bigmask)));
            cellser2=cellser.*bigmask+aveincell*~bigmask;
            aveintcelltime=squeeze(mean(mean(cellser2,1),2));
            if app.NormalizeTrace==1
                aveintcelltime2=aveintcelltime-mean(aveintcelltime(1:10));
                aveintcelltime2=aveintcelltime2/max(aveintcelltime2);
                %                 app.MinPeakProminenceEditField.Value=0.2;
                %                 app.MinPeakProminenceEditField_2.Value=0.2;
                % %                 app.MinPeakProm1=0.2;
                %                 app.MinPeakProm2=0.2;
            elseif app.NormalizeTrace==0
                aveintcelltime2=aveintcelltime;
            end
            % smooth average intensity trace?
            if app.SmoothAve==1;
                aveintcelltime2 = moving_average(aveintcelltime2,app.SmoothWindow);
            elseif app.SmoothAve==0
                aveintcelltime2=aveintcelltime2;
            end
            Y = moving_average(aveintcelltime2,app.MovAvRan);
            Y2=moving_average(aveintcelltime2-Y,2);
            cla(app.UIAxes2)
            plot(app.UIAxes2,1:size(app.maxZdatach1,3),aveintcelltime2)
            %findpeaks(app.UIAxes,aveintcelltime,1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm1,'MinPeakDistance',app.MinPeakDist1,'MinPeakWidth',app.MinPeakWidth1,'Annotate','extents')
            [pks1,locs1,w1,p1]=findpeaks(aveintcelltime2,1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm1,'MinPeakDistance',app.MinPeakDist1,'MinPeakWidth',app.MinPeakWidth1,'Annotate','extents');
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,locs1,pks1,'k*')
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,1:size(app.maxZdatach1,3),Y2+aveintcelltime2(1),'r')
            [pks2,locs2,w2,p2]=findpeaks(Y2+aveintcelltime2(1),1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm2,'MinPeakDistance',app.MinPeakDist2,'MinPeakWidth',app.MinPeakWidth2,'Annotate','extents');
            %findpeaks(app.UIAxes,Y2+aveintcelltime(1),1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm2,'MinPeakDistance',app.MinPeakDist2,'MinPeakWidth',app.MinPeakWidth2,'Annotate','extents')
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,locs2,pks2,'ko')
            hold(app.UIAxes2,"on");
            text(app.UIAxes2,5,1.05*max(aveintcelltime2),['# of peaks_{raw}=' num2str(length(pks1))],'FontSize',20,'Color',[0 0 1])
            text(app.UIAxes2,5,max(aveintcelltime2),['# of peaks_{detrend}=' num2str(length(pks2))],'FontSize',20,'Color',[1 0 0])
            %xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes2,[0.8*min(aveintcelltime2) 1.1*max(aveintcelltime2)])
        end

        % Value changed function: Displaythiscellzoominaxes1Button
        function Displaythiscellzoominaxes1ButtonValueChanged(app, event)
            value = app.Displaythiscellzoominaxes1Button.Value;
            app.DisplayCell=value;
        end

        % Value changed function: MinPeakProminenceEditField
        function MinPeakProminenceEditFieldValueChanged(app, event)
            value = app.MinPeakProminenceEditField.Value;
            app.MinPeakProm1=value;
        end

        % Value changed function: MinPeakDistanceEditField
        function MinPeakDistanceEditFieldValueChanged(app, event)
            value = app.MinPeakDistanceEditField.Value;
            app.MinPeakDist1=value;
        end

        % Value changed function: MinPeakWidthEditField
        function MinPeakWidthEditFieldValueChanged(app, event)
            value = app.MinPeakWidthEditField.Value;
            app.MinPeakWidth1=value;
        end

        % Value changed function: MinPeakProminenceEditField_2
        function MinPeakProminenceEditField_2ValueChanged(app, event)
            value = app.MinPeakProminenceEditField_2.Value;
            app.MinPeakProm2=value;
        end

        % Value changed function: MinPeakDistanceEditField_2
        function MinPeakDistanceEditField_2ValueChanged(app, event)
            value = app.MinPeakDistanceEditField_2.Value;
            app.MinPeakDist2=value;
        end

        % Value changed function: MinPeakWidthEditField_2
        function MinPeakWidthEditField_2ValueChanged(app, event)
            value = app.MinPeakWidthEditField_2.Value;
            app.MinPeakWidth2=value;
        end

        % Value changed function: 
        % UsePredefinedWatershedOutputsButton
        function UsePredefinedWatershedOutputsButtonValueChanged(app, event)
            value = app.UsePredefinedWatershedOutputsButton.Value;
            app.UsePreDefined=value;
        end

        % Button pushed function: SaveAnalysisParametersFileButton
        function SaveAnalysisParametersFileButtonPushed(app, event)
            thresh1a=app.thresh1a;
            thresh1b=app.thresh1b;
            thresh2=app.thresh2;
            areaUB=app.areaUB;
            areaLB=app.areaLB;
            MovAvRan=app.MovAvRan;
            cellsize=app.cellsize;
            choice1=app.choice1;
            choice2=app.choice2;
            MinPeakProm1=app.MinPeakProm1;
            MinPeakProm2=app.MinPeakProm2;
            MinPeakDist1=app.MinPeakDist1;
            MinPeakDist2=app.MinPeakDist2;
            MinPeakWidth1=app.MinPeakWidth1;
            MinPeakWidth2=app.MinPeakWidth2;
            NormalizeTrace=app.NormalizeTrace;
            SmoothAve=app.SmoothAve;
            SmoothWindow=app.SmoothWindow;
            save([app.fname(1:end-4) '_ParameterFile.mat'],'thresh1a','thresh1b','thresh2','areaUB','areaLB','MovAvRan','cellsize','choice1','choice2','MinPeakProm1','MinPeakProm2','MinPeakDist1','MinPeakDist2','MinPeakWidth1','MinPeakWidth2','NormalizeTrace','SmoothAve','SmoothWindow')
        end

        % Button pushed function: 
        % ApplyanalysistoallcellsusingdefinedsettingsButton
        function ApplyanalysistoallcellsusingdefinedsettingsButtonPushed(app, event)
            if isempty(app.Lb)
                load([app.fname(1:end-4) '_Segmentation.mat'],'Lb','thresh1a','thresh1b','thresh2','areaUB','areaLB','cellsize','choice1','choice2');
                app.Lb=Lb;
                app.thresh1a=thresh1a;
                app.thresh1b=thresh1b;
                app.thresh2=thresh2;
                app.areaUB=areaUB;
                app.areaLB=areaLB;
                app.cellsize=cellsize;
                app.choice1=choice1;
                app.choice2=choice2;
            end
            
            %             if isempty(app.cellser)
            %             load([app.fname(1:end-4) '_ParameterFile.mat'],'MovAvRan','MinPeakProm1','MinPeakProm2','MinPeakDist1','MinPeakDist2','MinPeakWidth1','MinPeakWidth2','NormalizeTrace','SmoothAve','SmoothWindow')
            %             app.MovAvRan=MovAvRan;
            %             app.MinPeakProm1=MinPeakProm1;
            %             app.MinPeakProm2=MinPeakProm2;
            %             app.MinPeakDist1=MinPeakDist1;
            %             app.MinPeakDist2=MinPeakDist2;
            %             app.MinPeakWidth1=MinPeakWidth1;
            %             app.MinPeakWidth2=MinPeakWidth2;
            %             app.NormalizeTrace=NormalizeTrace;
            %             app.SmoothAve=SmoothAve;
            %             app.SmoothWindow=SmoothWindow;
            %              end
            timetrans=zeros(1,max(app.Lb(:)));
            freq1=zeros(1,max(app.Lb(:)));
            freq2=zeros(1,max(app.Lb(:)));
            totTime=zeros(1,max(app.Lb(:)));
            numEvents1=zeros(1,max(app.Lb(:)));
            numEvents2=zeros(1,max(app.Lb(:)));
            T=table(zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1));
            T.Properties.VariableNames ={'Transit Time (frame)','Total Time (frame)','# Events raw','# Events Detrend','Freq raw (event/frame)','Freq detrend (event/frame)'};
            writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Summary Ca2+ Signaling');
            cla(app.UIAxes3)
            ylim(app.UIAxes3,[0,1])
            xlim(app.UIAxes3,[0,1])
            ph = patch(app.UIAxes3,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            % Create the percent-complete text that will be updated
            th = text(app.UIAxes3,1,1,'Extracting Average Intensity Per Cell...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for cell=1:max(app.Lb(:));
                
                maskcell=app.Lb==cell;
                [row,col]=find(maskcell);
                maskcell2=squeeze(maskcell(min(row):max(row),min(col):max(col)));
                cellser=squeeze(app.maxZdatach1(min(row):max(row),min(col):max(col),:));
                bigmask=repmat(maskcell2,[1 1 size(cellser,3)]);
                aveincell=mean(cellser(find(bigmask)));
                cellser2=cellser.*bigmask+aveincell*~bigmask;
                aveintcelltime=squeeze(mean(mean(cellser2,1),2));
                if app.NormalizeTrace==1
                    aveintcelltime2=aveintcelltime-mean(aveintcelltime(1:10));
                    aveintcelltime2=aveintcelltime2/max(aveintcelltime2);
                elseif app.NormalizeTrace==0
                    aveintcelltime2=aveintcelltime;
                end
                % smooth average intensity trace?
                if app.SmoothAve==1;
                    aveintcelltime2 = moving_average(aveintcelltime2,app.SmoothWindow);
                elseif app.SmoothAve==0
                    aveintcelltime2=aveintcelltime2;
                end
                Y = moving_average(aveintcelltime2,10);
                Y2=moving_average(aveintcelltime2-Y,2);
                [pks1,locs1,w1,p1]=findpeaks(aveintcelltime2,1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm1,'MinPeakDistance',app.MinPeakDist1,'MinPeakWidth',app.MinPeakWidth1,'Annotate','extents');
                [pks2,locs2,w2,p2]=findpeaks(Y2+aveintcelltime2(1),1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm2,'MinPeakDistance',app.MinPeakDist2,'MinPeakWidth',app.MinPeakWidth2,'Annotate','extents');
                
                Y2 = moving_average(diff(Y),5);
                [pkstrans,locstrans]=findpeaks(Y2,1:length(Y2),'Annotate','extents');
                indbad1=find(locstrans<10);
                indbad2=find(locstrans>size(cellser,3)-10);
                indbad=union(indbad1,indbad2);
                pkstrans=pkstrans(setdiff(1:length(locstrans),indbad));
                locstrans=locstrans(setdiff(1:length(locstrans),indbad));
                indtrans=find(pkstrans==max(pkstrans));
                timetrans(cell)=locstrans(indtrans);
                %using intial and final time, define how many events over time inteval...
                totTime(cell)=size(app.im,4)-timetrans(cell);
                numEvents1(cell)=length(pks1);
                numEvents2(cell)=length(pks2);
                freq1(cell)=numEvents1(cell)/totTime(cell);
                freq2(cell)=numEvents2(cell)/totTime(cell);
                
                T=table(pks1,locs1',w1',p1,'VariableNames',{'Amps1','Locs1','Width1','Prom1'});
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)]);
                T=table(pks2,locs2',w2',p2,'VariableNames',{'Amps2','Locs2','Width2','Prom2'});
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)],'Range',['F1:I' num2str(size(T,1)+1)]);
                T=table(aveintcelltime);
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)],'Range',['K1:K' num2str(size(T,1)+1)]);
                T=table([timetrans(cell) totTime(cell) numEvents1(cell) numEvents2(cell) freq1(cell) freq2(cell)]','RowNames',{'Transit Time (frame)','Total Time (frame)','# Events raw','# Events Detrend','Freq raw (event/frame)','Freq detrend (event/frame)'});
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)],'Range',['M1:N' num2str(size(T,1)+1)],'WriteRowNames',true);
                ph.XData = [0 cell/max(app.Lb(:)) cell/max(app.Lb(:)) 0];
                th.String = sprintf('Extracting Average Intensity Per Cell...%.0f%%',round(cell/max(app.Lb(:))*100));
                drawnow %update graphics
            end
            
            T=table(timetrans',totTime',numEvents1',numEvents2',freq1',freq2');
            T.Properties.VariableNames ={'Transit Time (frame)','Total Time (frame)','# Events raw','# Events Detrend','Freq raw (event/frame)','Freq detrend (event/frame)'};
            writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Summary Ca2+ Signaling');
            stats1=regionprops(app.Lb,'Area','Circularity','Perimeter','Solidity');
            T=struct2table(stats1);
            writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Summary Ca2+ Signaling','Range',['H1:K' num2str(size(T,1)+1)]);
            
            %% export ref image
            stats=regionprops(app.Lb,'Centroid');
            rgb=label2rgb(app.Lb,'jet','k','shuffle');
            xy=[stats.Centroid];
            
            cla(app.UIAxes)
            imagesc(app.UIAxes,rgb)
            hold(app.UIAxes,"on");
            for cel=1:size(stats,1)
                text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                hold(app.UIAxes,"on");
            end
            xlim(app.UIAxes,[1 size(app.im2,2)])
            ylim(app.UIAxes,[1 size(app.im2,1)])
            exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refImage.png']);
        end

        % Button pushed function: 
        % ApplybatchonseveraldatasetsusingsamesettingsButton
        function ApplybatchonseveraldatasetsusingsamesettingsButtonPushed(app, event)
            % select files to process
            [files, p] = uigetfile('*.czi;*.nd2;*.oir;*.oif;*.lif','Select all OME files to process','MultiSelect','on');
            cla(app.UIAxes3)
            % process files one by one
            for f=1:length(files)
                %load file
                fname = [p files{f}];
                app.fname=fname;
                [im,specs]=readOMEAlternativeGUI(app.UIAxes3,app.fname);
                app.im=double(im);
                app.DisplayCell=0;
                app.seriesSlider.Limits = [1 size(app.im,4)];
                app.maxZdatach1=squeeze(max(app.im(:,:,:,:,1),[],3));
                xlim(app.UIAxes,[1 size(app.im,2)])
                ylim(app.UIAxes,[1 size(app.im,1)])
                
                if app.UsePreDefined==1
                    load([app.fname(1:end-4) '_Segmentation.mat'],'Lb','thresh1a','thresh1b','thresh2','areaUB','areaLB','cellsize','choice1','choice2');
                    app.Lb=Lb;
                    app.thresh1a=thresh1a;
                    app.thresh1b=thresh1b;
                    app.thresh2=thresh2;
                    app.areaUB=areaUB;
                    app.areaLB=areaLB;
                    app.cellsize=cellsize;
                    app.choice1=choice1;
                    app.choice2=choice2;
                    
                elseif app.UsePreDefined==0
                    % define which images to work on
                    value=app.choice1;
                    if strcmp(value,'z=1; Ch=2')
                        app.im1=squeeze(mean(app.im(:,:,1,:,2),4));
                    elseif strcmp(value,'z=2; Ch=2')
                        app.im1=squeeze(mean(app.im(:,:,2,:,2),4));
                    elseif strcmp(value,'z=3; Ch=2')
                        app.im1=squeeze(mean(app.im(:,:,3,:,2),4));
                    elseif strcmp(value,'max Z; Ch=2')
                        app.im1=squeeze(mean(max(app.im(:,:,:,:,2),[],3),4));
                    elseif strcmp(value,'<Z>; Ch=2')
                        app.im1=squeeze(mean(mean(app.im(:,:,:,:,2),3),4));
                    end
                    % image 2
                    value=app.choice2;
                    if strcmp(value,'z=1; Ch=2')
                        app.im2=squeeze(mean(app.im(:,:,1,:,2),4));
                    elseif strcmp(value,'z=2; Ch=2')
                        app.im2=squeeze(mean(app.im(:,:,2,:,2),4));
                    elseif strcmp(value,'z=3; Ch=2')
                        app.im2=squeeze(mean(app.im(:,:,3,:,2),4));
                    elseif strcmp(value,'max Z; Ch=2')
                        app.im2=squeeze(mean(max(app.im(:,:,:,:,2),[],3),4));
                    elseif strcmp(value,'<Z>; Ch=2')
                        app.im2=squeeze(mean(mean(app.im(:,:,:,:,2),3),4));
                    end
                    % 1) define bw for centers search
                    app.norm1=app.im1/max(app.im1(:));
                    app.bw1=app.norm1>app.thresh1a;
                    % find centers in bw
                    D = bwdist(~app.bw1);
                    app.out=pkfnd(D/max(D(:)),app.thresh1b,app.cellsize);
                    
                    % 2) find binary from maximum intensity projection in
                    % channel 1
                    app.norm2=app.im2/max(app.im2(:));
                    bw2=app.norm2>app.thresh2;
                    % filter binary
                    cc = bwconncomp(bw2);
                    stats = regionprops(cc, 'Area');
                    idx = find([stats.Area] >pi*(app.cellsize^2));
                    app.bw2 = ismember(labelmatrix(cc), idx);
                    % 3) combine info apply watershed
                    [app.aveim,h2]=smoothSeries(app.norm2,1.5,'g','p');
                    mask_em=accumarray(round([app.out(:,2),app.out(:,1)]),ones(1,size(app.out,1)), [size(app.bw2,1),size(app.bw2,2)]);
                    app.I_eq_c = imcomplement((app.aveim/max(app.aveim(:))).^2);
                    app.I_mod = imimposemin(app.I_eq_c, ~app.bw2 | mask_em);
                    app.Lb = watershed(app.I_mod,8);
                    % filter Lb
                    Lb=app.Lb;
                    firststat = regionprops(Lb);
                    areas=[firststat.Area];
                    ind1=find(areas<app.areaUB);
                    ind2=find(areas>app.areaLB);
                    indGood=intersect(ind1,ind2);
                    Lb2=zeros(size(Lb));
                    for i=1:length(indGood)
                        ind=find(Lb==indGood(i));
                        Lb2(ind)=i;
                    end
                    app.Lb=Lb2;
                end
                % Now apply the analysis on each cell and save data
                timetrans=zeros(1,max(app.Lb(:)));
                freq1=zeros(1,max(app.Lb(:)));
                freq2=zeros(1,max(app.Lb(:)));
                totTime=zeros(1,max(app.Lb(:)));
                numEvents1=zeros(1,max(app.Lb(:)));
                numEvents2=zeros(1,max(app.Lb(:)));
                T=table(zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1),zeros(max(app.Lb(:)),1));
                T.Properties.VariableNames ={'Transit Time (frame)','Total Time (frame)','# Events raw','# Events Detrend','Freq raw (event/frame)','Freq detrend (event/frame)'};
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Summary Ca2+ Signaling');
                cla(app.UIAxes3)
                ylim(app.UIAxes3,[0,1])
                xlim(app.UIAxes3,[0,1])
                ph = patch(app.UIAxes3,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                % Create the percent-complete text that will be updated
                th = text(app.UIAxes3,1,1,'Extracting Average Intensity Per Cell...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for cell=1:max(app.Lb(:));
                    maskcell=app.Lb==cell;
                    [row,col]=find(maskcell);
                    maskcell2=squeeze(maskcell(min(row):max(row),min(col):max(col)));
                    cellser=squeeze(app.maxZdatach1(min(row):max(row),min(col):max(col),:));
                    bigmask=repmat(maskcell2,[1 1 size(cellser,3)]);
                    aveincell=mean(cellser(find(bigmask)));
                    cellser2=cellser.*bigmask+aveincell*~bigmask;
                    aveintcelltime=squeeze(mean(mean(cellser2,1),2));
                    if app.NormalizeTrace==1
                        aveintcelltime2=aveintcelltime-mean(aveintcelltime(1:10));
                        aveintcelltime2=aveintcelltime2/max(aveintcelltime2);
                    elseif app.NormalizeTrace==0
                        aveintcelltime2=aveintcelltime;
                    end
                    % smooth average intensity trace?
                    if app.SmoothAve==1;
                        aveintcelltime2 = moving_average(aveintcelltime2,app.SmoothWindow);
                    elseif app.SmoothAve==0
                        aveintcelltime2=aveintcelltime2;
                    end
                    Y = moving_average(aveintcelltime2,10);
                    Y2=moving_average(aveintcelltime2-Y,2);
                    [pks1,locs1,w1,p1]=findpeaks(aveintcelltime2,1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm1,'MinPeakDistance',app.MinPeakDist1,'MinPeakWidth',app.MinPeakWidth1,'Annotate','extents');
                    [pks2,locs2,w2,p2]=findpeaks(Y2+aveintcelltime2(1),1:size(app.maxZdatach1,3),'MinPeakProminence',app.MinPeakProm2,'MinPeakDistance',app.MinPeakDist2,'MinPeakWidth',app.MinPeakWidth2,'Annotate','extents');
                    
                    Y2 = moving_average(diff(Y),5);
                    [pkstrans,locstrans]=findpeaks(Y2,1:length(Y2),'Annotate','extents');
                    indbad1=find(locstrans<10);
                    indbad2=find(locstrans>size(cellser,3)-10);
                    indbad=union(indbad1,indbad2);
                    pkstrans=pkstrans(setdiff(1:length(locstrans),indbad));
                    locstrans=locstrans(setdiff(1:length(locstrans),indbad));
                    indtrans=find(pkstrans==max(pkstrans));
                    timetrans(cell)=locstrans(indtrans);
                    %using intial and final time, define how many events over time inteval...
                    totTime(cell)=size(app.im,4)-timetrans(cell);
                    numEvents1(cell)=length(pks1);
                    numEvents2(cell)=length(pks2);
                    freq1(cell)=numEvents1(cell)/totTime(cell);
                    freq2(cell)=numEvents2(cell)/totTime(cell);
                    
                    T=table(pks1,locs1',w1',p1,'VariableNames',{'Amps1','Locs1','Width1','Prom1'});
                    writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)]);
                    T=table(pks2,locs2',w2',p2,'VariableNames',{'Amps2','Locs2','Width2','Prom2'});
                    writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)],'Range',['F1:I' num2str(size(T,1)+1)]);
                    T=table(aveintcelltime);
                    writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)],'Range',['K1:K' num2str(size(T,1)+1)]);
                    T=table([timetrans(cell) totTime(cell) numEvents1(cell) numEvents2(cell) freq1(cell) freq2(cell)]','RowNames',{'Transit Time (frame)','Total Time (frame)','# Events raw','# Events Detrend','Freq raw (event/frame)','Freq detrend (event/frame)'});
                    writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet',['Cell' num2str(cell)],'Range',['M1:N' num2str(size(T,1)+1)],'WriteRowNames',true);
                    ph.XData = [0 cell/max(app.Lb(:)) cell/max(app.Lb(:)) 0];
                    th.String = sprintf('Extracting Average Intensity Per Cell...%.0f%%',round(cell/max(app.Lb(:))*100));
                    drawnow %update graphics
                end
                
                T=table(timetrans',totTime',numEvents1',numEvents2',freq1',freq2');
                T.Properties.VariableNames ={'Transit Time (frame)','Total Time (frame)','# Events raw','# Events Detrend','Freq raw (event/frame)','Freq detrend (event/frame)'};
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Summary Ca2+ Signaling');
                stats1=regionprops(app.Lb,'Area','Circularity','Perimeter','Solidity');
                T=struct2table(stats1);
                writetable(T,[app.fname(1:end-4) '_stats.xlsx'],'Sheet','Summary Ca2+ Signaling','Range',['H1:K' num2str(size(T,1)+1)]);
                
                %% export ref image
                stats=regionprops(app.Lb,'Centroid');
                rgb=label2rgb(app.Lb,'jet','k','shuffle');
                xy=[stats.Centroid];
                
                cla(app.UIAxes)
                imagesc(app.UIAxes,rgb)
                hold(app.UIAxes,"on");
                for cel=1:size(stats,1)
                    text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                    hold(app.UIAxes,"on");
                end
                xlim(app.UIAxes,[1 size(app.im1,2)])
                ylim(app.UIAxes,[1 size(app.im1,1)])
                exportgraphics(app.UIAxes,[app.fname(1:end-4) '_refImage.png'])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadOMEImageDataButton = uibutton(app.UIFigure, 'push');
            app.LoadOMEImageDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOMEImageDataButtonPushed, true);
            app.LoadOMEImageDataButton.Text = 'Load OME Image Data';
            app.ApplybatchonseveraldatasetsusingsamesettingsButton = uibutton(app.UIFigure, 'push');
            app.ApplybatchonseveraldatasetsusingsamesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplybatchonseveraldatasetsusingsamesettingsButtonPushed, true);
            app.ApplybatchonseveraldatasetsusingsamesettingsButton.Text = 'Apply batch on several data sets using same settings';
            app.seriesSliderLabel = uilabel(app.UIFigure);
            app.seriesSliderLabel.Text = 'series';
            app.seriesSlider = uislider(app.UIFigure);
            app.seriesSlider.Limits = [1 100];
            app.seriesSlider.ValueChangingFcn = createCallbackFcn(app, @seriesSliderValueChanging, true);
            app.seriesSlider.Value = 1;
            app.RawdatatracepeaksdetectionPanel = uipanel(app.UIFigure);
            app.RawdatatracepeaksdetectionPanel.Title = 'Raw data trace peaks detection';
            app.MinPeakProminenceEditFieldLabel = uilabel(app.RawdatatracepeaksdetectionPanel);
            app.MinPeakProminenceEditFieldLabel.Text = 'Min. Peak Prominence ';
            app.MinPeakProminenceEditField = uieditfield(app.RawdatatracepeaksdetectionPanel, 'numeric');
            app.MinPeakProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakProminenceEditFieldValueChanged, true);
            app.MinPeakProminenceEditField.Value = 3;
            app.MinPeakDistanceEditFieldLabel = uilabel(app.RawdatatracepeaksdetectionPanel);
            app.MinPeakDistanceEditFieldLabel.Text = 'Min. Peak Distance';
            app.MinPeakDistanceEditField = uieditfield(app.RawdatatracepeaksdetectionPanel, 'numeric');
            app.MinPeakDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakDistanceEditFieldValueChanged, true);
            app.MinPeakDistanceEditField.Value = 2;
            app.MinPeakWidthEditFieldLabel = uilabel(app.RawdatatracepeaksdetectionPanel);
            app.MinPeakWidthEditFieldLabel.Text = 'Min. Peak Width';
            app.MinPeakWidthEditField = uieditfield(app.RawdatatracepeaksdetectionPanel, 'numeric');
            app.MinPeakWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakWidthEditFieldValueChanged, true);
            app.MinPeakWidthEditField.Value = 2;
            app.DefineTheCellCenterPositionsPanel = uipanel(app.UIFigure);
            app.DefineTheCellCenterPositionsPanel.Title = 'Define The Cell Center Positions';
            app.CellCentersDetectionSliderLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.CellCentersDetectionSliderLabel.Text = 'Cell Centers Detection';
            app.CellCentersDetectionSlider = uislider(app.DefineTheCellCenterPositionsPanel);
            app.CellCentersDetectionSlider.Limits = [0 10];
            app.CellCentersDetectionSlider.ValueChangedFcn = createCallbackFcn(app, @CellCentersDetectionSliderValueChanged, true);
            app.CellCentersDetectionSlider.ValueChangingFcn = createCallbackFcn(app, @CellCentersDetectionSliderValueChanging, true);
            app.CellradiusfilterEditFieldLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.CellradiusfilterEditFieldLabel.Text = 'Cell radius filter';
            app.CellradiusfilterEditField = uieditfield(app.DefineTheCellCenterPositionsPanel, 'numeric');
            app.CellradiusfilterEditField.Limits = [1 Inf];
            app.CellradiusfilterEditField.ValueChangedFcn = createCallbackFcn(app, @CellradiusfilterEditFieldValueChanged, true);
            app.CellradiusfilterEditField.Value = 10;
            app.SelectimagetouseforfindingcellcentersDropDownLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.SelectimagetouseforfindingcellcentersDropDownLabel.Text = 'Select image to use for finding cell centers';
            app.SelectimagetouseforfindingcellcentersDropDown = uidropdown(app.DefineTheCellCenterPositionsPanel);
            app.SelectimagetouseforfindingcellcentersDropDown.Items = {'z=1; Ch=2', 'z=2; Ch=2', 'z=3; Ch=2', 'max Z; Ch=2', '<Z>; Ch=2'};
            app.SelectimagetouseforfindingcellcentersDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectimagetouseforfindingcellcentersDropDownValueChanged, true);
            app.SelectimagetouseforfindingcellcentersDropDown.Value = 'z=1; Ch=2';
            app.DefineBinarizationThresholdSliderLabel = uilabel(app.DefineTheCellCenterPositionsPanel);
            app.DefineBinarizationThresholdSliderLabel.Text = 'Define Binarization Threshold';
            app.DefineBinarizationThresholdSlider = uislider(app.DefineTheCellCenterPositionsPanel);
            app.DefineBinarizationThresholdSlider.Limits = [0 10];
            app.DefineBinarizationThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @DefineBinarizationThresholdSliderValueChanged, true);
            app.DefineBinarizationThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @DefineBinarizationThresholdSliderValueChanging, true);
            app.CalculateBinaryForCenterDetectionButton = uibutton(app.DefineTheCellCenterPositionsPanel, 'push');
            app.CalculateBinaryForCenterDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateBinaryForCenterDetectionButtonPushed, true);
            app.CalculateBinaryForCenterDetectionButton.Text = 'Calculate Binary For Center Detection';
            app.DetectCellCentersButton = uibutton(app.DefineTheCellCenterPositionsPanel, 'push');
            app.DetectCellCentersButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCellCentersButtonPushed, true);
            app.DetectCellCentersButton.Text = 'Detect Cell Centers';
            app.ApplyWatershedAndDefineCellAreasPanel = uipanel(app.UIFigure);
            app.ApplyWatershedAndDefineCellAreasPanel.Title = 'Apply Watershed And Define Cell Areas';
            app.ApplyWatershedButton = uibutton(app.ApplyWatershedAndDefineCellAreasPanel, 'push');
            app.ApplyWatershedButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyWatershedButtonPushed, true);
            app.ApplyWatershedButton.Text = 'Apply Watershed';
            app.FinalSegmentationButton = uibutton(app.ApplyWatershedAndDefineCellAreasPanel, 'push');
            app.FinalSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @FinalSegmentationButtonPushed, true);
            app.FinalSegmentationButton.Text = 'Final Segmentation ';
            app.DisplayResultsDropDownLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.DisplayResultsDropDownLabel.Text = 'Display Results';
            app.DisplayResultsDropDown = uidropdown(app.ApplyWatershedAndDefineCellAreasPanel);
            app.DisplayResultsDropDown.Items = {'Inverse Average', 'Inverse Average Constrained', 'Watershed Results'};
            app.DisplayResultsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DisplayResultsDropDownOpening, true);
            app.DisplayResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayResultsDropDownValueChanged, true);
            app.DisplayResultsDropDown.Value = 'Inverse Average';
            app.AreaUpperBoundEditFieldLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.AreaUpperBoundEditFieldLabel.Text = 'Area Upper Bound';
            app.AreaUpperBoundEditField = uieditfield(app.ApplyWatershedAndDefineCellAreasPanel, 'numeric');
            app.AreaUpperBoundEditField.Limits = [0 Inf];
            app.AreaUpperBoundEditField.ValueChangedFcn = createCallbackFcn(app, @AreaUpperBoundEditFieldValueChanged, true);
            app.AreaUpperBoundEditField.Value = 600;
            app.AreaLowerBoundEditFieldLabel = uilabel(app.ApplyWatershedAndDefineCellAreasPanel);
            app.AreaLowerBoundEditFieldLabel.Text = 'Area Lower Bound';
            app.AreaLowerBoundEditField = uieditfield(app.ApplyWatershedAndDefineCellAreasPanel, 'numeric');
            app.AreaLowerBoundEditField.Limits = [0 Inf];
            app.AreaLowerBoundEditField.ValueChangedFcn = createCallbackFcn(app, @AreaLowerBoundEditFieldValueChanged, true);
            app.AreaLowerBoundEditField.Value = 50;
            app.DefineOverallCellMaskPanel = uipanel(app.UIFigure);
            app.DefineOverallCellMaskPanel.Title = 'Define Overall Cell Mask';
            app.BinariseImage2SliderLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.BinariseImage2SliderLabel.Text = 'Binarise Image 2';
            app.BinariseImage2Slider = uislider(app.DefineOverallCellMaskPanel);
            app.BinariseImage2Slider.Limits = [0 10];
            app.BinariseImage2Slider.ValueChangedFcn = createCallbackFcn(app, @BinariseImage2SliderValueChanged, true);
            app.BinariseImage2Slider.ValueChangingFcn = createCallbackFcn(app, @BinariseImage2SliderValueChanging, true);
            app.ImageforoverallcellshapebinaryDropDownLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.ImageforoverallcellshapebinaryDropDownLabel.Text = ' Image for overall cell shape binary';
            app.ImageforoverallcellshapebinaryDropDown = uidropdown(app.DefineOverallCellMaskPanel);
            app.ImageforoverallcellshapebinaryDropDown.Items = {'z=1; Ch=2', 'z=2; Ch=2', 'z=3; Ch=2', 'max Z; Ch=2', '<Z>; Ch=2'};
            app.ImageforoverallcellshapebinaryDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageforoverallcellshapebinaryDropDownValueChanged, true);
            app.ImageforoverallcellshapebinaryDropDown.Value = 'z=1; Ch=2';
            app.CalculateOverallCellMaskButton = uibutton(app.DefineOverallCellMaskPanel, 'push');
            app.CalculateOverallCellMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateOverallCellMaskButtonPushed, true);
            app.CalculateOverallCellMaskButton.Text = 'Calculate Overall Cell Mask';
            app.DetrendeddatatracepeaksdetectionPanel = uipanel(app.UIFigure);
            app.DetrendeddatatracepeaksdetectionPanel.Title = 'Detrended data trace peaks detection';
            app.MinPeakProminenceEditField_2Label = uilabel(app.DetrendeddatatracepeaksdetectionPanel);
            app.MinPeakProminenceEditField_2Label.Text = 'Min. Peak Prominence ';
            app.MinPeakProminenceEditField_2 = uieditfield(app.DetrendeddatatracepeaksdetectionPanel, 'numeric');
            app.MinPeakProminenceEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinPeakProminenceEditField_2ValueChanged, true);
            app.MinPeakProminenceEditField_2.Value = 2;
            app.MinPeakDistanceEditField_2Label = uilabel(app.DetrendeddatatracepeaksdetectionPanel);
            app.MinPeakDistanceEditField_2Label.Text = 'Min. Peak Distance';
            app.MinPeakDistanceEditField_2 = uieditfield(app.DetrendeddatatracepeaksdetectionPanel, 'numeric');
            app.MinPeakDistanceEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinPeakDistanceEditField_2ValueChanged, true);
            app.MinPeakDistanceEditField_2.Value = 3;
            app.MinPeakWidthEditField_2Label = uilabel(app.DetrendeddatatracepeaksdetectionPanel);
            app.MinPeakWidthEditField_2Label.Text = 'Min. Peak Width';
            app.MinPeakWidthEditField_2 = uieditfield(app.DetrendeddatatracepeaksdetectionPanel, 'numeric');
            app.MinPeakWidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinPeakWidthEditField_2ValueChanged, true);
            app.MinPeakWidthEditField_2.Value = 3;
            app.ApplyanalysistoallcellsusingdefinedsettingsButton = uibutton(app.UIFigure, 'push');
            app.ApplyanalysistoallcellsusingdefinedsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyanalysistoallcellsusingdefinedsettingsButtonPushed, true);
            app.ApplyanalysistoallcellsusingdefinedsettingsButton.Text = 'Apply analysis to all cells using defined settings';
            app.DisplayingIntensityTracePerCellPanel = uipanel(app.UIFigure);
            app.DisplayingIntensityTracePerCellPanel.Title = 'Displaying Intensity Trace Per Cell';
            app.DisplayIntensityCellEditFieldLabel = uilabel(app.DisplayingIntensityTracePerCellPanel);
            app.DisplayIntensityCellEditFieldLabel.Text = 'Display Intensity Cell #';
            app.DisplayIntensityCellEditField = uieditfield(app.DisplayingIntensityTracePerCellPanel, 'numeric');
            app.DisplayIntensityCellEditField.Limits = [1 Inf];
            app.DisplayIntensityCellEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayIntensityCellEditFieldValueChanged, true);
            app.DisplayIntensityCellEditField.Value = 1;
            app.MovingAverageRangeframesOverallShapeDetrendingEditFieldLabel = uilabel(app.DisplayingIntensityTracePerCellPanel);
            app.MovingAverageRangeframesOverallShapeDetrendingEditFieldLabel.Text = 'Moving Average Range (frames) Overall Shape Detrending';
            app.MovingAverageRangeframesOverallShapeDetrendingEditField = uieditfield(app.DisplayingIntensityTracePerCellPanel, 'numeric');
            app.MovingAverageRangeframesOverallShapeDetrendingEditField.Limits = [0 Inf];
            app.MovingAverageRangeframesOverallShapeDetrendingEditField.ValueChangedFcn = createCallbackFcn(app, @MovingAverageRangeframesOverallShapeDetrendingEditFieldValueChanged, true);
            app.MovingAverageRangeframesOverallShapeDetrendingEditField.Value = 10;
            app.DisplaycellintensitytracesandpeaksfoundButton = uibutton(app.DisplayingIntensityTracePerCellPanel, 'push');
            app.DisplaycellintensitytracesandpeaksfoundButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaycellintensitytracesandpeaksfoundButtonPushed, true);
            app.DisplaycellintensitytracesandpeaksfoundButton.Text = 'Display cell intensity traces and peaks found';
            app.Displaythiscellzoominaxes1Button = uibutton(app.DisplayingIntensityTracePerCellPanel, 'state');
            app.Displaythiscellzoominaxes1Button.ValueChangedFcn = createCallbackFcn(app, @Displaythiscellzoominaxes1ButtonValueChanged, true);
            app.Displaythiscellzoominaxes1Button.Text = 'Display this cell zoom in axes 1';
            app.MovingAverageRangeframesAveIntensitySmoothingEditFieldLabel = uilabel(app.DisplayingIntensityTracePerCellPanel);
            app.MovingAverageRangeframesAveIntensitySmoothingEditFieldLabel.Text = 'Moving Average Range (frames) Ave Intensity Smoothing';
            app.MovingAverageRangeframesAveIntensitySmoothingEditField = uieditfield(app.DisplayingIntensityTracePerCellPanel, 'numeric');
            app.MovingAverageRangeframesAveIntensitySmoothingEditField.Limits = [0 Inf];
            app.MovingAverageRangeframesAveIntensitySmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @MovingAverageRangeframesAveIntensitySmoothingEditFieldValueChanged, true);
            app.MovingAverageRangeframesAveIntensitySmoothingEditField.Value = 3;
            app.SmoothButton = uibutton(app.DisplayingIntensityTracePerCellPanel, 'state');
            app.SmoothButton.ValueChangedFcn = createCallbackFcn(app, @SmoothButtonValueChanged, true);
            app.SmoothButton.Text = 'Smooth';
            app.NormalizeAverageIntensityTracesButton = uibutton(app.DisplayingIntensityTracePerCellPanel, 'state');
            app.NormalizeAverageIntensityTracesButton.ValueChangedFcn = createCallbackFcn(app, @NormalizeAverageIntensityTracesButtonValueChanged, true);
            app.NormalizeAverageIntensityTracesButton.Text = 'Normalize Average Intensity Traces';
            app.LoadAnalysisParametersFileButton = uibutton(app.UIFigure, 'push');
            app.LoadAnalysisParametersFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnalysisParametersFileButtonPushed, true);
            app.LoadAnalysisParametersFileButton.Text = 'Load Analysis Parameters File';
            app.SaveAnalysisParametersFileButton = uibutton(app.UIFigure, 'push');
            app.SaveAnalysisParametersFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisParametersFileButtonPushed, true);
            app.SaveAnalysisParametersFileButton.Text = 'Save Analysis Parameters File';
            app.SaveSegmentationResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveSegmentationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSegmentationResultsButtonPushed, true);
            app.SaveSegmentationResultsButton.Text = 'Save Segmentation Results';
            app.UsePredefinedWatershedOutputsButton = uibutton(app.UIFigure, 'state');
            app.UsePredefinedWatershedOutputsButton.ValueChangedFcn = createCallbackFcn(app, @UsePredefinedWatershedOutputsButtonValueChanged, true);
            app.UsePredefinedWatershedOutputsButton.Text = 'Use Pre-defined Watershed Outputs';
            app.ManuallySelectROIsForQuantificationPanel = uipanel(app.UIFigure);
            app.ManuallySelectROIsForQuantificationPanel.Title = 'Manually Select ROIs For Quantification';
            app.NumberofROIsToSelectEditFieldLabel = uilabel(app.ManuallySelectROIsForQuantificationPanel);
            app.NumberofROIsToSelectEditFieldLabel.Text = 'Number of ROIs To Select';
            app.NumberofROIsToSelectEditField = uieditfield(app.ManuallySelectROIsForQuantificationPanel, 'numeric');
            app.NumberofROIsToSelectEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofROIsToSelectEditFieldValueChanged, true);
            app.NumberofROIsToSelectEditField.Value = 3;
            app.ROItypeDropDownLabel = uilabel(app.ManuallySelectROIsForQuantificationPanel);
            app.ROItypeDropDownLabel.Text = 'ROI type';
            app.ROItypeDropDown = uidropdown(app.ManuallySelectROIsForQuantificationPanel);
            app.ROItypeDropDown.Items = {'Circle', 'Ellipse', 'Rectangle', 'Polygon'};
            app.ROItypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ROItypeDropDownValueChanged, true);
            app.ROItypeDropDown.Value = 'Ellipse';
            app.SelectROIsInsideImagetoDefineCellsButton = uibutton(app.ManuallySelectROIsForQuantificationPanel, 'push');
            app.SelectROIsInsideImagetoDefineCellsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIsInsideImagetoDefineCellsButtonPushed, true);
            app.SelectROIsInsideImagetoDefineCellsButton.Text = 'Select ROIs Inside Image to Define Cells';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Progress')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentCellsExtractCalciumSignalingStatsV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end